classdef GradeCalculatorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StudentNameEditField       matlab.ui.control.EditField
        StudentNameLabel           matlab.ui.control.Label
        PleaseenterstudentsgradesandanycommentsassociatedforAssignments15belowLabel  matlab.ui.control.Label
        CommentsTextArea           matlab.ui.control.TextArea
        CommentsTextAreaLabel      matlab.ui.control.Label
        GradePercentagePanel       matlab.ui.container.Panel
        LetterGradeLabel           matlab.ui.control.Label
        PercentageLabel            matlab.ui.control.Label
        GradeCalculatorLabel       matlab.ui.control.Label
        Assignment5EditField       matlab.ui.control.NumericEditField
        Assignment5EditFieldLabel  matlab.ui.control.Label
        Assignment4EditField       matlab.ui.control.NumericEditField
        Assignment4EditFieldLabel  matlab.ui.control.Label
        Assignment3EditField       matlab.ui.control.NumericEditField
        Assignment3EditFieldLabel  matlab.ui.control.Label
        Assignment2EditField       matlab.ui.control.NumericEditField
        Assignment2EditFieldLabel  matlab.ui.control.Label
        Assignment1EditField       matlab.ui.control.NumericEditField
        Assignment1EditFieldLabel  matlab.ui.control.Label
        CalculateGradeButton       matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function CalculateButtonPushed(app, event)
delete(app.UIFigure);
          
        end

        % Button pushed function: CalculateGradeButton
        function CalculateGradeButtonPushed(app, event)
              a1 = app.Assignment1EditField.Value;
            a2 = app.Assignment2EditField.Value;
            a3 = app.Assignment3EditField.Value;
            a4 = app.Assignment4EditField.Value;
            a5 = app.Assignment5EditField.Value;
     percent = mean([a1 a2 a3 a4 a5]);
            if percent >= 90
             letter = 'A';
                 elseif percent >= 80
                letter = 'B';
                elseif percent >= 70
                letter = 'C';
                elseif percent >= 60
                letter = 'D';
              else
                letter = 'F';
            end
     % This creates a variable called percent and stores the input values
     % from the five assignment grades, once the push button is pressed, 
     % the action then calculates the averag of the five input values, 
     % and associates a letter grade with value earned. 

    app.PercentageLabel.Text = sprintf('Percentage: %.2f%%', percent);
    app.LetterGradeLabel.Text = letter; 
  % This changes the Percent Label in the bottom right corner of the GUI to
  % the percent which was calculated from the average of the assignments.
  % Additionally, the Letter Grade Label changes text to the letter
  % variable which corresponds to the grade percentage.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateGradeButton = uibutton(app.UIFigure, 'push');
            app.CalculateGradeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGradeButtonPushed, true);
            app.CalculateGradeButton.Text = 'Calculate Grade';
            app.Assignment1EditFieldLabel = uilabel(app.UIFigure);
            app.Assignment1EditFieldLabel.Text = 'Assignment 1';
            app.Assignment1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Assignment2EditFieldLabel = uilabel(app.UIFigure);
            app.Assignment2EditFieldLabel.Text = 'Assignment 2';
            app.Assignment2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Assignment3EditFieldLabel = uilabel(app.UIFigure);
            app.Assignment3EditFieldLabel.Text = 'Assignment 3';
            app.Assignment3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Assignment4EditFieldLabel = uilabel(app.UIFigure);
            app.Assignment4EditFieldLabel.Text = 'Assignment 4';
            app.Assignment4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Assignment5EditFieldLabel = uilabel(app.UIFigure);
            app.Assignment5EditFieldLabel.Text = 'Assignment 5';
            app.Assignment5EditField = uieditfield(app.UIFigure, 'numeric');
            app.GradeCalculatorLabel = uilabel(app.UIFigure);
            app.GradeCalculatorLabel.Text = 'Grade Calculator ';
            app.GradePercentagePanel = uipanel(app.UIFigure);
            app.GradePercentagePanel.Title = 'Grade Percentage ';
            app.PercentageLabel = uilabel(app.GradePercentagePanel);
            app.PercentageLabel.Text = 'Percentage';
            app.LetterGradeLabel = uilabel(app.GradePercentagePanel);
            app.LetterGradeLabel.Text = 'Letter Grade';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments ';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.PleaseenterstudentsgradesandanycommentsassociatedforAssignments15belowLabel = uilabel(app.UIFigure);
            app.PleaseenterstudentsgradesandanycommentsassociatedforAssignments15belowLabel.Text = 'Please enter student''s grades and any comments associated for Assignments 1-5 below:';
            app.StudentNameLabel = uilabel(app.UIFigure);
            app.StudentNameLabel.Text = 'Student Name:';
            app.StudentNameEditField = uieditfield(app.UIFigure, 'text');
            app.StudentNameEditField.Value = '"Enter Name"';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradeCalculatorGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end